classdef FIIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FIRFilterDesignerLabel          matlab.ui.control.Label
        ChoosethemethodLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        windowedsincTab                 matlab.ui.container.Tab
        WindowDropDownLabel             matlab.ui.control.Label
        WindowDropDown                  matlab.ui.control.DropDown
        AdditionalfactorEditFieldLabel  matlab.ui.control.Label
        AdditionalfactorEditField       matlab.ui.control.NumericEditField
        leastsquareTab                  matlab.ui.container.Tab
        NFreqPointsEditFieldLabel       matlab.ui.control.Label
        NFreqPointsEditField            matlab.ui.control.NumericEditField
        CutoffFreqEditFieldLabel        matlab.ui.control.Label
        CutoffFreqEditField             matlab.ui.control.NumericEditField
        weightedleastsquareTab          matlab.ui.container.Tab
        weightsEditFieldLabel           matlab.ui.control.Label
        weightsEditField                matlab.ui.control.EditField
        NPointsandSmaplingFrequencyPanel  matlab.ui.container.Panel
        NTapsEditFieldLabel             matlab.ui.control.Label
        NTapsEditField                  matlab.ui.control.NumericEditField
        fsEditFieldLabel                matlab.ui.control.Label
        fsEditField                     matlab.ui.control.NumericEditField
        FilterTypeButtonGroup           matlab.ui.container.ButtonGroup
        LowPassButton                   matlab.ui.control.RadioButton
        BandPassButton                  matlab.ui.control.RadioButton
        HighPassButton                  matlab.ui.control.RadioButton
        DesignButton                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        TimeDomainTab                   matlab.ui.container.Tab
        UIAxes_time                     matlab.ui.control.UIAxes
        FrequencyDomainTab              matlab.ui.container.Tab
        UIAxes_freq                     matlab.ui.control.UIAxes
        PhaseResponseTab                matlab.ui.container.Tab
        UIAxes_phase                    matlab.ui.control.UIAxes
        FrequencyindBTab                matlab.ui.container.Tab
        UIAxes_freqdB                   matlab.ui.control.UIAxes
        SaveButton                      matlab.ui.control.Button
        cutoff_label                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        Fs = 10; % the sampling frequency -> Default Value = 10;
        L; % Filter length 
        Lf; % Filter length in freq
        m; % frequency indices vector
        fc; %cut-off frequency
        freq_axis; % the frequency axis used
        tab='windowed sinc'; %to detect the tab activated --> The design type.
        pass_type='low';%to save the type chosen of the filter (low, high, band pass);
        final_filter; %To print and save; [ taps ]
        real_length;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            app.L = app.NTapsEditField.Value; % Filter length in time domain
            app.Fs = app.fsEditField.Value; %FS
            app.Lf = app.NFreqPointsEditField.Value; %Number of points in F-D
            win_type = app.WindowDropDown.Value;
            if(strcmp(app.tab,'windowed sinc'))
                [filter_time, filter_freq] = windowed_sinc(app.L,win_type,app.AdditionalfactorEditField.Value,app.Fs,app.pass_type); %Design the filter coffecient
            elseif (strcmp(app.tab,'least square'))
                app.fc = app.CutoffFreqEditField.Value;
                [filter_time, filter_freq] = least_square(app.Lf,app.L,app.fc,app.pass_type);
            elseif (strcmp(app.tab,'weighted least square'))
                app.fc = app.CutoffFreqEditField.Value;
                W= str2num(app.weightsEditField.Value); %Using str2double here makes errors.
                [filter_time, filter_freq] = weighted_least_square(app.Lf,app.L,app.fc,W,app.pass_type);
            end
            %Update the number of taps
               app.L = length(filter_time);
             %Start Plotting the impulse response
                t = 0 : 1/app.Fs : (1/app.Fs*app.L)-1/app.Fs; %to adjust the time axis to be in seconds
                stem(app.UIAxes_time,t,filter_time); % Plot the Magnitude response
             %Plotting the frequency response
                app.freq_axis = 0:app.Fs/length(filter_freq):(app.Fs/2)-app.Fs/length(filter_freq);
                half_filter_freq = filter_freq(1:length(filter_freq)/2);
                plot(app.UIAxes_freq,app.freq_axis,abs(half_filter_freq));
             %Plotting the frequency response in dB
               filter_dB = mag2db(abs(half_filter_freq));
               plot(app.UIAxes_freqdB,app.freq_axis,filter_dB)
             %Plotting the Phase response
                [phi, w] = phasez(filter_time);
                plot(app.UIAxes_phase,w,phi);
             %Finalize and Printing the cut-off frequency
                app.final_filter = filter_time;
                app.real_length = length(filter_time);
               if (~strcmp(app.tab,'windowed sinc') && ~strcmp(app.pass_type,'High Pass') && ~strcmp(app.pass_type,'Band Pass') )
               app.cutoff_label.Text = {[' Fc = ' num2str(app.Fs*app.fc)  ' Hz'] ; ''};
               elseif (strcmp(app.tab,'windowed sinc') && ~strcmp(app.pass_type,'High Pass') && ~strcmp(app.pass_type,'Band Pass')) 
               app.cutoff_label.Text = {['Fc = ' num2str(app.Fs*2/app.L)  ' Hz'] ; ''};
               else
                   app.cutoff_label.Text = ' ';
               end
        end

        % Size changed function: leastsquareTab
        function leastsquareTabSizeChanged(app, event)
            % None
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
           app.tab = app.TabGroup.SelectedTab.Title;
        end

        % Callback function
        function FilterTypeButtonGroupSelectionChanged(app, event)
         
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged2(app, event)
            app.pass_type = app.FilterTypeButtonGroup.SelectedObject.Text;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fileID = fopen('filter.txt','Wt');
            for i=1:1:app.real_length
                fprintf(fileID,'%d',app.final_filter(i));
                fprintf(fileID,' \n');
            end
                fclose(fileID);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FIRFilterDesignerLabel = uilabel(app.UIFigure);
            app.FIRFilterDesignerLabel.Text = {'FIR Filter Designer'; ''};
            app.ChoosethemethodLabel = uilabel(app.UIFigure);
            app.ChoosethemethodLabel.Text = {'Choose the method:'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.windowedsincTab = uitab(app.TabGroup);
            app.windowedsincTab.Title = 'windowed sinc';
            app.WindowDropDownLabel = uilabel(app.windowedsincTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.windowedsincTab);
            app.WindowDropDown.Items = {'Rectangular', 'Blackman', 'Chebyshev', 'Kaiser'};
            app.WindowDropDown.Value = 'Rectangular';
            app.AdditionalfactorEditFieldLabel = uilabel(app.windowedsincTab);
            app.AdditionalfactorEditFieldLabel.Text = 'Additional factor';
            app.AdditionalfactorEditField = uieditfield(app.windowedsincTab, 'numeric');
            app.leastsquareTab = uitab(app.TabGroup);
            app.leastsquareTab.SizeChangedFcn = createCallbackFcn(app, @leastsquareTabSizeChanged, true);
            app.leastsquareTab.Title = 'least square';
            app.NFreqPointsEditFieldLabel = uilabel(app.leastsquareTab);
            app.NFreqPointsEditFieldLabel.Text = 'N. Freq Points';
            app.NFreqPointsEditField = uieditfield(app.leastsquareTab, 'numeric');
            app.CutoffFreqEditFieldLabel = uilabel(app.leastsquareTab);
            app.CutoffFreqEditFieldLabel.Text = 'Cut-off Freq.';
            app.CutoffFreqEditField = uieditfield(app.leastsquareTab, 'numeric');
            app.weightedleastsquareTab = uitab(app.TabGroup);
            app.weightedleastsquareTab.Title = 'weighted least square';
            app.weightsEditFieldLabel = uilabel(app.weightedleastsquareTab);
            app.weightsEditFieldLabel.Text = 'weights';
            app.weightsEditField = uieditfield(app.weightedleastsquareTab, 'text');
            app.NPointsandSmaplingFrequencyPanel = uipanel(app.UIFigure);
            app.NPointsandSmaplingFrequencyPanel.Title = 'N. Points and Smapling Frequency';
            app.NTapsEditFieldLabel = uilabel(app.NPointsandSmaplingFrequencyPanel);
            app.NTapsEditFieldLabel.Text = 'N# Taps';
            app.NTapsEditField = uieditfield(app.NPointsandSmaplingFrequencyPanel, 'numeric');
            app.fsEditFieldLabel = uilabel(app.NPointsandSmaplingFrequencyPanel);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.NPointsandSmaplingFrequencyPanel, 'numeric');
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged2, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowPassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowPassButton.Text = 'Low Pass';
            app.LowPassButton.Value = true;
            app.BandPassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandPassButton.Text = 'Band Pass';
            app.HighPassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighPassButton.Text = 'High Pass';
            app.DesignButton = uibutton(app.UIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Design';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup2);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes_time = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_time, 'Magnitude Response')
            xlabel(app.UIAxes_time, 'Filter Taps (s)')
            ylabel(app.UIAxes_time, 'Magnitude')
            app.FrequencyDomainTab = uitab(app.TabGroup2);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.UIAxes_freq = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes_freq, 'Frequency Response')
            xlabel(app.UIAxes_freq, 'frequency (Hz)')
            ylabel(app.UIAxes_freq, 'magnitude |H(w)|')
            app.PhaseResponseTab = uitab(app.TabGroup2);
            app.PhaseResponseTab.Title = 'Phase Response';
            app.UIAxes_phase = uiaxes(app.PhaseResponseTab);
            title(app.UIAxes_phase, 'Phase Response')
            xlabel(app.UIAxes_phase, 'frequency (rad/s)')
            ylabel(app.UIAxes_phase, 'angle (rad)')
            app.FrequencyindBTab = uitab(app.TabGroup2);
            app.FrequencyindBTab.Title = 'Frequency in dB';
            app.UIAxes_freqdB = uiaxes(app.FrequencyindBTab);
            title(app.UIAxes_freqdB, 'Frequency Response in dB')
            xlabel(app.UIAxes_freqdB, 'frequency (Hz)')
            ylabel(app.UIAxes_freqdB, 'magnitude in dB')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.cutoff_label = uilabel(app.UIFigure);
            app.cutoff_label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIIR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end